classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Label_2        matlab.ui.control.Label
        Label          matlab.ui.control.Label
        ComputeButton  matlab.ui.control.Button
        LoadButton     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
 filename = uigetfile('logo_csse.png');
% read image
im=imread(filename);
image =app.UIFigure;
% % plot image
axe =uiaxes(image,'Position',[82,297,195,102]);
imshow(im,'Parent',axe); 
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
  % get filename
filename = uigetfile('logo_csse.png');
% read image
im=imread(filename);
% compute image
imgray = rgb2gray(im);
BW = imbinarize(imgray);
% plot images
image1 =app.UIFigure;
axe1 =uiaxes(image1,'Position',[76,183,185,58]);
imshow(BW*255,'Parent',axe1);
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '画面表示';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '二値化';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end